classdef mixerCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMONATIMSMixerCalibrationv03UIFigure  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MixerCalibrationTab        matlab.ui.container.Tab
        RunTxCalButton             matlab.ui.control.StateButton
        AnalogRxFilterCalButton    matlab.ui.control.StateButton
        RunRxCalibrationButton     matlab.ui.control.StateButton
        Status                     matlab.ui.control.Label
        DSOSettingsTab             matlab.ui.container.Tab
        VISATypeEditFieldLabel     matlab.ui.control.Label
        VISATypeEditField          matlab.ui.control.EditField
        VISAAddressEditFieldLabel  matlab.ui.control.Label
        VISAAddressEditField       matlab.ui.control.EditField
        HelpTab                    matlab.ui.container.Tab
        SimulatorModeCheckBox      matlab.ui.control.CheckBox
        ReadSingleSidebandButton   matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            close all;
            clc;
            try
                addpath('functions\');
            catch
                warning('Functions folder not found');
            end
        end

        % Callback function
        function AmpSliderValueChanged(app, event)
            
        end

        % Value changed function: RunTxCalButton
        function RunTxCalButtonValueChanged(app, event)
            
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            
            global VISAtype;
            global VISAaddr;
            VISAaddr = app.VISAAddressEditField.Value;
            VISAtype = app.VISATypeEditField.Value;
            
            
            app.Status.Text = 'Running Tx Calibration';
            app.Status.FontColor = 'Black';
            try
                readTxCal
                app.Status.Text = 'Tx Calibration Successful';
                app.Status.FontColor = [0.47 0.67 0.19];
            catch ME
                warning('Error Running readTxCal');
                warning(ME.message);
                app.Status.Text = 'An Error Occured During Tx Calibration';
                app.Status.FontColor = [0.64 0.08 0.18];
            end
            app.RunTxCalButton.Value = 0;
            % Disable/Enable visablity to bring app window back to the foreground
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 0;
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 1;
        end

        % Value changed function: RunRxCalibrationButton
        function RunRxCalibrationButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            
            global VISAtype;
            global VISAaddr;
            VISAaddr = app.VISAAddressEditField.Value;
            VISAtype = app.VISATypeEditField.Value;
            
            
            app.Status.Text = 'Running Rx Calibration';
            app.Status.FontColor = 'Black';
            
            try
                readRxCal
                app.Status.Text = 'Rx Calibration Successful';
                app.Status.FontColor = [0.47 0.67 0.19];
            catch ME
                warning('Error Running readRxCal');
                warning(ME.message);
                app.Status.Text = 'An Error Occured During Rx Calibration';
                app.Status.FontColor = [0.64 0.08 0.18];
            end
            
            app.RunRxCalibrationButton.Value = 0;
            % Disable/Enable visablity to bring app window back to the foreground
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 0;
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 1;
        end

        % Callback function
        function BuildRxCalFilesButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            buildRxCal
            app.BuildRxCalFilesButton.Value = 0;
            app.RunRxCalibrationButton.Enable = 1;
            
        end

        % Value changed function: AnalogRxFilterCalButton
        function AnalogRxFilterCalButtonValueChanged(app, event)
            
            
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            if ~SIM_MODE
                
            VISAaddr = app.VISAAddressEditField.Value;
            VISAtype = app.VISATypeEditField.Value;
                
                app.Status.Text = 'Setting Up Filter Calibration';
                app.Status.FontColor = 'Black';
                try
                    % Send Pulse to AWG
                    buildFiltCal();
                    % Set Rigol to Filter Viewing mode
                    setRigol(4,[],[],VISAtype,VISAaddr);
                    
                    if isfile('Data Files\filter_tuned.mat') && isfile('Data Files\filter_untuned.mat')
                        a = load('Data Files\filter_untuned.mat');
                        figure;
                        subplot(211);
                        plot(a.tq,a.F1rx,a.tq,a.F2rx);
                        grid on; grid minor;
                        legend('Filter One','Filter Two');
                        title('Simulated Untuned Filters Impulse Response');
                        
                        a = load('Data Files\filter_tuned.mat');
                        subplot(212)
                        plot(a.tq,a.F1rx,a.tq,a.F2rx);
                        grid on; grid minor;
                        legend('Filter One','Filter Two');
                        title('Simulated Tuned Filters Impulse Response');
                        
                    end
                    
                    app.Status.Text = 'DSO Set Successful';
                    app.Status.FontColor = [0.47 0.67 0.19];
                catch ME
                    warning('Error Running setRigol');
                    warning(ME.message);
                    app.Status.Text = 'An Error Occured';
                    app.Status.FontColor = [0.64 0.08 0.18];
                end


            end
            
            app.AnalogRxFilterCalButton.Value = 0;
            
        end

        % Value changed function: ReadSingleSidebandButton
        function ReadSingleSidebandButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            readRx_sb_calcd
            app.ReadSingleSidebandButton.Value = 0;
            
        end

        % Value changed function: SimulatorModeCheckBox
        function SimulatorModeCheckBoxValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMONATIMSMixerCalibrationv03UIFigure = uifigure;
            app.EMONATIMSMixerCalibrationv03UIFigure.Name = 'EMONA TIMS Mixer Calibration v0.3';
            app.TabGroup = uitabgroup(app.EMONATIMSMixerCalibrationv03UIFigure);
            app.MixerCalibrationTab = uitab(app.TabGroup);
            app.MixerCalibrationTab.Title = 'Mixer Calibration';
            app.RunTxCalButton = uibutton(app.MixerCalibrationTab, 'state');
            app.RunTxCalButton.ValueChangedFcn = createCallbackFcn(app, @RunTxCalButtonValueChanged, true);
            app.RunTxCalButton.Text = 'Run Tx Cal';
            app.AnalogRxFilterCalButton = uibutton(app.MixerCalibrationTab, 'state');
            app.AnalogRxFilterCalButton.ValueChangedFcn = createCallbackFcn(app, @AnalogRxFilterCalButtonValueChanged, true);
            app.AnalogRxFilterCalButton.Text = 'Analog Rx Filter Cal';
            app.RunRxCalibrationButton = uibutton(app.MixerCalibrationTab, 'state');
            app.RunRxCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunRxCalibrationButtonValueChanged, true);
            app.RunRxCalibrationButton.Text = 'Run Rx Calibration';
            app.Status = uilabel(app.MixerCalibrationTab);
            app.Status.Text = '';
            app.DSOSettingsTab = uitab(app.TabGroup);
            app.DSOSettingsTab.Title = 'DSO Settings';
            app.VISATypeEditFieldLabel = uilabel(app.DSOSettingsTab);
            app.VISATypeEditFieldLabel.Text = 'VISA Type';
            app.VISATypeEditField = uieditfield(app.DSOSettingsTab, 'text');
            app.VISATypeEditField.Value = 'KEYSIGHT';
            app.VISAAddressEditFieldLabel = uilabel(app.DSOSettingsTab);
            app.VISAAddressEditFieldLabel.Text = 'VISA Address';
            app.VISAAddressEditField = uieditfield(app.DSOSettingsTab, 'text');
            app.VISAAddressEditField.Value = 'USB0::0x1AB1::0x04B1::DS4A194800709::0::INSTR';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.SimulatorModeCheckBox = uicheckbox(app.HelpTab);
            app.SimulatorModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulatorModeCheckBoxValueChanged, true);
            app.SimulatorModeCheckBox.Text = 'Simulator Mode';
            app.ReadSingleSidebandButton = uibutton(app.EMONATIMSMixerCalibrationv03UIFigure, 'state');
            app.ReadSingleSidebandButton.ValueChangedFcn = createCallbackFcn(app, @ReadSingleSidebandButtonValueChanged, true);
            app.ReadSingleSidebandButton.Enable = 'off';
            app.ReadSingleSidebandButton.Text = 'Read Single Sideband';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mixerCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMONATIMSMixerCalibrationv03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMONATIMSMixerCalibrationv03UIFigure)
        end
    end
end